classdef manual_reconstruction_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NextButton                   matlab.ui.control.Button
        HorSliderLabel               matlab.ui.control.Label
        HorSlider                    matlab.ui.control.Slider
        VerLabel                     matlab.ui.control.Label
        VerSlider                    matlab.ui.control.Slider
        RotSliderLabel               matlab.ui.control.Label
        RotSlider                    matlab.ui.control.Slider
        UIAxes3                      matlab.ui.control.UIAxes
        ConfirmButton                matlab.ui.control.Button
        UIAxes3_2                    matlab.ui.control.UIAxes
        CurrentfileLabel             matlab.ui.control.Label
        StartingframeEditFieldLabel  matlab.ui.control.Label
        StartingframeEditField       matlab.ui.control.NumericEditField
        IntervalEditFieldLabel       matlab.ui.control.Label
        IntervalEditField            matlab.ui.control.NumericEditField
        EndingframeEditFieldLabel    matlab.ui.control.Label
        EndingframeEditField         matlab.ui.control.NumericEditField
        ExitButton                   matlab.ui.control.Button
        HomeButton                   matlab.ui.control.Button
        UIAxes3_3                    matlab.ui.control.UIAxes
        SaveandExitButton            matlab.ui.control.Button
        MatlabImregCheckBox          matlab.ui.control.CheckBox
        LoadcheckpointButton         matlab.ui.control.Button
        stackSliderLabel             matlab.ui.control.Label
        stackSlider                  matlab.ui.control.Slider
        registernowButton            matlab.ui.control.Button
        correctionCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        data % contains mydata_bw
        filename
        frame_tracker
        current
        next
        modified_next
        tform_values
        tform_mat
        all_RAs
        overscan_RA
        current_combined_tform
        previous_image
        changes
        changes_with_imreg
        matching_changes
        recon_diff_plot
        end_reached
        use_matlab
        checkpoint_exists
        metric
        optimizer
        use_correction
    end
    
    methods (Access = private)
        
        function transformation_matrix = make_tform_mat(~, hor_shift, ver_shift, theta)
%             transformation_matrix = affine2d([cosd(theta) sind(theta) hor_shift; -sind(theta) cosd(theta) ver_shift; 0 0 1]');
            transformation_matrix = affine2d([cosd(theta) sind(theta) 0; -sind(theta) cosd(theta) 0; hor_shift ver_shift 1]);
        end
        
        function reset_transform(app)
            app.tform_values.hor = 0;
            app.tform_values.ver = 0;
            app.tform_values.rot = 0;
            app.HorSlider.Value = 0;
            app.VerSlider.Value = 0;
            app.RotSlider.Value = 0;
            app.tform_mat = app.make_tform_mat(0, 0, 0);
        end
        
%         function find_matching_difference(app) % modified is always greater in size than original 
%             mod = app.modified_next;
%             org = app.modified_next;
%             if size(mod, 1) * size(mod, 2) < size(org, 1) * size(org, 2)
%                 
%             end
%         end
        
        function update_frame(app)
            app.tform_mat = app.make_tform_mat(app.tform_values.hor, -app.tform_values.ver, -app.tform_values.rot);
%             app.modified_next = imwarp(app.next, app.tform_mat);
            [app.modified_next, ~] = imwarp(app.next, app.all_RAs, app.tform_mat, 'FillValues', 0, 'OutputView', app.overscan_RA);
%             imshow(app.modified_next, out_RA, 'Parent', app.UIAxes2);
%             imshowpair(app.current, app.all_RAs, app.modified_next, out_RA, 'diff', 'Parent', app.UIAxes3)
            imshowpair(app.current, app.all_RAs, app.modified_next, app.overscan_RA, 'Parent', app.UIAxes3)            
%             app.matching_changes = [app.matching_changes, norm(app.modified_next - app.next)];
%             obj = imshowpair(app.next, app.modified_next, 'diff', 'Parent', app.UIAxes4);
        end
        
        function save_variables(app)
            previous_image = app.previous_image;
            recon_diff_plot = app.recon_diff_plot;
            frame_tracker = app.frame_tracker;
            current_combined_tform = app.current_combined_tform;
            save(sprintf('Manual_recon_%s(%d:%d:%d).mat', app.filename, app.frame_tracker.start, app.frame_tracker.interval, app.frame_tracker.cur_idx),...
                "previous_image", "recon_diff_plot", "frame_tracker", "current_combined_tform");
        end
        
        function use_matlab_imreg(app)
            tformReg = imregtform(app.next, app.current, 'rigid', app.optimizer, app.metric); % mapping next to current
            tformReg_mat = tformReg.T;
            app.HorSlider.Value = tformReg_mat(3, 1);
            app.VerSlider.Value = tformReg_mat(3, 2);
            app.RotSlider.Value = acosd(tformReg_mat(1, 1));
            app.tform_mat = tformReg;
            [app.modified_next, ~] = imwarp(app.next, app.all_RAs, tformReg, 'FillValues', 0, 'OutputView', app.overscan_RA);
            imshowpair(app.current, app.all_RAs, app.modified_next, app.overscan_RA, 'Parent', app.UIAxes3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.filename, path] = uigetfile(".mat", "Select a file");
            drawnow;
            figure(app.UIFigure)
            app.CurrentfileLabel.Text = sprintf("Current file: %s", erase(app.filename, ".mat"));
            app.data = load(fullfile(path, app.filename));
            
            % Tunable parameters
            start_default = 1;
            interval_default = 20;
            end_default = app.data.l;
            
            app.frame_tracker = struct('start', start_default, 'cur_idx', start_default, 'interval', interval_default, 'end', end_default);
            app.tform_values = struct('hor', 0, 'ver', 0, 'rot', 0);
            app.tform_mat = app.make_tform_mat(app.tform_values.hor, app.tform_values.ver, app.tform_values.rot);
            app.all_RAs = imref2d([app.data.h, app.data.w], [1, app.data.h], [1, app.data.w]);
            app.current_combined_tform = affine2d([1 0 0; 0 1 0; 0 0 1]);
            app.previous_image = zeros(app.data.h, app.data.w);
            app.overscan_RA = imref2d([3*app.data.h, 2*app.data.w], [1, 2*app.data.h], [1, 2*app.data.w]);
            app.changes = [];
            app.changes_with_imreg = [];
            app.matching_changes = [];
            app.end_reached = false;
            
            app.MatlabImregCheckBox.Value = true;
            app.use_matlab = true;
            app.metric = registration.metric.MeanSquares();
            app.optimizer = registration.optimizer.RegularStepGradientDescent();
            app.optimizer.MaximumIterations = 200;
            app.optimizer.MinimumStepLength = 1e-4;
            
            app.checkpoint_exists = false;
            app.stackSlider.Value = app.frame_tracker.cur_idx;
            app.stackSlider.Limits = [app.frame_tracker.start, app.frame_tracker.end];
            
            app.correctionCheckBox.Value = true;
            app.use_correction = true;
            
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            reset_transform(app);
            app.stackSlider.Value = app.frame_tracker.cur_idx;
            app.NextButton.Visible = 'off';
            cur_idx = app.frame_tracker.cur_idx;
            next_idx = cur_idx + app.frame_tracker.interval;
            
            if next_idx < app.frame_tracker.end
                app.ConfirmButton.Visible = 'on';
                app.current = medfilt2(app.data.mydata_bw(:, :, cur_idx));
                app.next = medfilt2(app.data.mydata_bw(:, :, next_idx));
                imshowpair(app.data.mydata_bw(:, :, cur_idx), app.overscan_RA, app.data.mydata_bw(:, :, next_idx), app.overscan_RA, 'Parent', app.UIAxes3)
                title(app.UIAxes3, sprintf('Matching frame %d and %d', cur_idx, next_idx));
                app.frame_tracker.cur_idx = next_idx;
                if next_idx + app.frame_tracker.interval > app.frame_tracker.end
                    app.end_reached = true;
                end
                if app.use_matlab
                    use_matlab_imreg(app);
                end
            end
        end

        % Value changed function: StartingframeEditField
        function StartingframeEditFieldValueChanged(app, event)
            value = app.StartingframeEditField.Value;
            app.frame_tracker.start = value;
            app.frame_tracker.cur_idx = value;
            app.stackSlider.Value = app.frame_tracker.cur_idx;
            app.stackSlider.Limits = [app.frame_tracker.start, app.frame_tracker.end];
            app.stackSlider.MajorTicks = app.frame_tracker.start:app.frame_tracker.interval:app.frame_tracker.end;
        end

        % Value changed function: IntervalEditField
        function IntervalEditFieldValueChanged(app, event)
            value = app.IntervalEditField.Value;
            app.frame_tracker.interval = value;
            app.stackSlider.Value = app.frame_tracker.cur_idx;
            app.stackSlider.Limits = [app.frame_tracker.start, app.frame_tracker.end];
            app.stackSlider.MajorTicks = app.frame_tracker.start:app.frame_tracker.interval:app.frame_tracker.end;
        end

        % Value changed function: EndingframeEditField
        function EndingframeEditFieldValueChanged(app, event)
            value = app.EndingframeEditField.Value;
            if value == -1
                value = app.data.l;
            end
            app.frame_tracker.end = value;
            app.stackSlider.Value = app.frame_tracker.cur_idx;
            app.stackSlider.Limits = [app.frame_tracker.start, app.frame_tracker.end];
            app.stackSlider.MajorTicks = app.frame_tracker.start:app.frame_tracker.interval:app.frame_tracker.end;
        end

        % Value changing function: HorSlider
        function HorSliderValueChanging(app, event)
            changingValue = event.Value;
            app.tform_values.hor = changingValue;
            update_frame(app);
        end

        % Value changing function: VerSlider
        function VerSliderValueChanging(app, event)
            changingValue = event.Value;
            app.tform_values.ver = changingValue;
            update_frame(app);
        end

        % Value changing function: RotSlider
        function RotSliderValueChanging(app, event)
            changingValue = event.Value;
            app.tform_values.rot = changingValue;
            update_frame(app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
%             app.current_combined_tform = affine2d(app.current_combined_tform.T * app.tform_mat.T);
            app.current_combined_tform = affine2d(app.tform_mat.T * app.current_combined_tform.T);
            if sum(sum(app.previous_image ~= 0)) == 0
                app.previous_image = imwarp(app.next, app.all_RAs, app.current_combined_tform, 'FillValues', 0, 'OutputView', app.overscan_RA);
                old_image = app.previous_image;
            else
                old_image = app.previous_image;
                if app.use_correction % once we know what our newest reconstruction is, we register it against the previous reconstruction to improve accuracy
                    incoming = imwarp(app.next, app.all_RAs, app.current_combined_tform, 'FillValues', 0, 'OutputView', app.overscan_RA); % best match
%                     prev_and_incoming = max(old_image, incoming); % I shouldn't accumulate here yet!
%                     secondary_tform = imregtform(prev_and_incoming, app.previous_image, 'rigid', app.optimizer, app.metric); % mapping next to current
%                     app.previous_image = imwarp(prev_and_incoming, app.overscan_RA, secondary_tform, 'FillValues', 0, 'OutputView', app.overscan_RA);
                    secondary_tform = imregtform(incoming, app.previous_image, 'rigid', app.optimizer, app.metric);
                    tformed_incoming = imwarp(incoming, app.overscan_RA, secondary_tform, 'FillValues', 0, 'OutputView', app.overscan_RA);
                    app.previous_image = max(app.previous_image, tformed_incoming);
                    app.changes_with_imreg = [app.changes_with_imreg, norm(app.previous_image - old_image)];
                else
                    tformed = imwarp(app.next, app.all_RAs, app.current_combined_tform, 'FillValues', 0, 'OutputView', app.overscan_RA);
                    app.previous_image = max(app.previous_image, tformed);
                    app.changes = [app.changes, norm(app.previous_image - old_image)];
                end
            end
            
%             imshow(app.previous_image, 'Parent', app.UIAxes3_3);

            imshowpair(old_image, app.overscan_RA, app.previous_image, app.overscan_RA, 'Parent', app.UIAxes3_3);
            plot(app.UIAxes3_2, 1:length(app.changes), app.changes, 'r-', 'LineWidth', 5); hold on
            plot(app.UIAxes3_2, 1:length(app.changes_with_imreg), app.changes_with_imreg, 'b-', 'LineWidth', 3); hold off
            
            app.ConfirmButton.Visible = 'off';
            if ~app.end_reached
                app.NextButton.Visible = 'on';
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            imshowpair(app.current, app.overscan_RA, app.next, app.overscan_RA, 'Parent', app.UIAxes3)
            if app.frame_tracker.cur_idx > app.frame_tracker.start
                app.stackSlider.Value = app.frame_tracker.cur_idx - app.frame_tracker.interval;
            else
                app.stackSlider.Value = app.frame_tracker.cur_idx;
            end
            reset_transform(app)
        end

        % Button pushed function: SaveandExitButton
        function SaveandExitButtonPushed(app, event)
            save_variables(app);
            delete(app);
        end

        % Value changed function: MatlabImregCheckBox
        function MatlabImregCheckBoxValueChanged(app, event)
            value = app.MatlabImregCheckBox.Value;
            app.use_matlab = value;
        end

        % Button pushed function: LoadcheckpointButton
        function LoadcheckpointButtonPushed(app, event)
            app.checkpoint_exists = true;
            [checkpoint, path] = uigetfile(".mat");
            cp = load(fullfile(path, checkpoint));
            app.previous_image = cp.previous_image;
            app.frame_tracker = cp.frame_tracker;
            app.StartingframeEditField.Value = app.frame_tracker.start;
            app.IntervalEditField.Value = app.frame_tracker.interval;
            app.EndingframeEditField.Value = app.frame_tracker.end;
            
        end

        % Value changing function: stackSlider
        function stackSliderValueChanging(app, event)
            changingValue = event.Value;
            imshow(app.data.mydata_bw(:, :, int64(changingValue)), [], 'Parent', app.UIAxes3);
        end

        % Button pushed function: registernowButton
        function registernowButtonPushed(app, event)
            use_matlab_imreg(app);
        end

        % Value changed function: correctionCheckBox
        function correctionCheckBoxValueChanged(app, event)
            value = app.correctionCheckBox.Value;
            app.use_correction = value;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
            clc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.HorSliderLabel = uilabel(app.UIFigure);
            app.HorSliderLabel.Text = 'Hor';
            app.HorSlider = uislider(app.UIFigure);
            app.HorSlider.Limits = [-300 300];
            app.HorSlider.ValueChangingFcn = createCallbackFcn(app, @HorSliderValueChanging, true);
            app.VerLabel = uilabel(app.UIFigure);
            app.VerLabel.Text = 'Ver';
            app.VerSlider = uislider(app.UIFigure);
            app.VerSlider.Limits = [-300 300];
            app.VerSlider.ValueChangingFcn = createCallbackFcn(app, @VerSliderValueChanging, true);
            app.RotSliderLabel = uilabel(app.UIFigure);
            app.RotSliderLabel.Text = 'Rot';
            app.RotSlider = uislider(app.UIFigure);
            app.RotSlider.Limits = [-60 60];
            app.RotSlider.ValueChangingFcn = createCallbackFcn(app, @RotSliderValueChanging, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Overlaid')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Current progress')
            xlabel(app.UIAxes3_2, '# of frames reconstructed')
            ylabel(app.UIAxes3_2, 'L2 norm of difference')
            app.CurrentfileLabel = uilabel(app.UIFigure);
            app.CurrentfileLabel.Text = 'Current file: ';
            app.StartingframeEditFieldLabel = uilabel(app.UIFigure);
            app.StartingframeEditFieldLabel.Text = 'Starting frame';
            app.StartingframeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingframeEditField.ValueChangedFcn = createCallbackFcn(app, @StartingframeEditFieldValueChanged, true);
            app.StartingframeEditField.Value = -1;
            app.IntervalEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalEditFieldValueChanged, true);
            app.IntervalEditField.Value = -1;
            app.EndingframeEditFieldLabel = uilabel(app.UIFigure);
            app.EndingframeEditFieldLabel.Text = 'Ending frame';
            app.EndingframeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndingframeEditField.ValueChangedFcn = createCallbackFcn(app, @EndingframeEditFieldValueChanged, true);
            app.EndingframeEditField.Value = -1;
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Reconstruction so far')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            app.UIAxes3_3.YDir = 'reverse';
            app.SaveandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExitButtonPushed, true);
            app.SaveandExitButton.Text = 'Save and Exit';
            app.MatlabImregCheckBox = uicheckbox(app.UIFigure);
            app.MatlabImregCheckBox.ValueChangedFcn = createCallbackFcn(app, @MatlabImregCheckBoxValueChanged, true);
            app.MatlabImregCheckBox.Text = 'Matlab Imreg';
            app.LoadcheckpointButton = uibutton(app.UIFigure, 'push');
            app.LoadcheckpointButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcheckpointButtonPushed, true);
            app.LoadcheckpointButton.Text = 'Load checkpoint';
            app.stackSliderLabel = uilabel(app.UIFigure);
            app.stackSliderLabel.Text = 'stack';
            app.stackSlider = uislider(app.UIFigure);
            app.stackSlider.ValueChangingFcn = createCallbackFcn(app, @stackSliderValueChanging, true);
            app.registernowButton = uibutton(app.UIFigure, 'push');
            app.registernowButton.ButtonPushedFcn = createCallbackFcn(app, @registernowButtonPushed, true);
            app.registernowButton.Text = 'register now';
            app.correctionCheckBox = uicheckbox(app.UIFigure);
            app.correctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @correctionCheckBoxValueChanged, true);
            app.correctionCheckBox.Text = 'correction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_reconstruction_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end